classdef image_colourisation_vJT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_3                     matlab.ui.control.Label
        KnownPixelsDesiredLabel     matlab.ui.control.Label
        percentage                  matlab.ui.control.NumericEditField
        ParametersLabel             matlab.ui.control.Label
        delta                       matlab.ui.control.NumericEditField
        Label_2                     matlab.ui.control.Label
        p                           matlab.ui.control.NumericEditField
        EditField_3Label_2          matlab.ui.control.Label
        sigma_2                     matlab.ui.control.NumericEditField
        EditField_2Label_2          matlab.ui.control.Label
        sigma_1                     matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        SaveImageButton             matlab.ui.control.Button
        RecolourisedImageLabel      matlab.ui.control.Label
        Image_4                     matlab.ui.control.Image
        GreyscalewithOriginalColouringLabel  matlab.ui.control.Label
        Image_3                     matlab.ui.control.Image
        GreyscaleLabel              matlab.ui.control.Label
        Image_2                     matlab.ui.control.Image
        OriginalLabel               matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UploadImageButton           matlab.ui.control.Button
        SamplingOptionButtonGroup   matlab.ui.container.ButtonGroup
        RandomButton                matlab.ui.control.Button
        UniformButton               matlab.ui.control.Button
        AreaSelectionButton         matlab.ui.control.Button
        CustomPointButton           matlab.ui.control.Button
        RecolouristaionButtonGroup  matlab.ui.container.ButtonGroup
        CompactlySupportedButton    matlab.ui.control.Button
        GaussianButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        OriginalImage
        GreyscaleImage
        width
        height
        total_pixels
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg'}, 'Select an Image');
            if isequal(file, 0)
                return;
            end
            original=imread(fullfile(path, file));
            app.Image.ImageSource=original;
            greyscale=repmat(rgb2gray(original),[1,1,3]);
            app.Image_2.ImageSource=greyscale;
            
            app.OriginalImage=original;
            app.GreyscaleImage=greyscale;
        end

        % Button pushed function: UniformButton
        function UniformButtonPushed(app, event)
            [k,l,~]=size(app.OriginalImage);
            m=k*l;
            proportion=abs(app.percentage.Value);
            if proportion>100
                proportion=100;
            end
            grid=round(sqrt(1/(proportion/100)),0);
            uniform=app.GreyscaleImage;
            for row=1:grid:k
                for col=1:grid:l
                    uniform(row,col,:)=app.OriginalImage(row,col,:);  
    end
            end
            uialert(app.UIFigure, "Uniform Grid applied successfully!", "Success", "Icon", "success");
            app.Image_3.ImageSource=uniform;
        end

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
            [k,l,~]=size(app.OriginalImage);
            m=k*l;
            proportion=round(abs(app.percentage.Value),0);
            if proportion>100
                proportion=100;
            end
            points=(k*l)*proportion/100; 
            random=app.GreyscaleImage;
            i=0;
            while i<points
                row=randi(k,1); %random gridpoint between 1 and k
                col=randi(l,1); %random gridpoint between 1 and l
                random(row,col,:)=app.OriginalImage(row,col,:);
                i=i+1;
            end
            uialert(app.UIFigure, "Random Grid applied successfully!", "Success", "Icon", "success");
            app.Image_3.ImageSource=random;         
        end

        % Button pushed function: AreaSelectionButton
        function AreaSelectionButtonPushed(app, event)
            [k,l,~]=size(app.OriginalImage);
            m=k*l;
            greyscale=app.GreyscaleImage;
            original=app.OriginalImage;
            manual=greyscale;
            figure;
            imshow(greyscale);
            title('Select a Region to Recolour');
            region=drawfreehand();
            mask=region.createMask();
            for i=1:3
                manual(:,:,i)=uint8(mask.*double(original(:,:,i))+(1-mask).*double(manual(:,:,i)));
            end
            uialert(app.UIFigure, "Area Selection applied successfully!", "Success", "Icon", "success");
            app.Image_3.ImageSource=manual;   
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            [k,l,~]=size(app.OriginalImage);
            m=k*l;
            C=app.Image_3.ImageSource;
            A=app.Image_2.ImageSource;
          
            F_r=C(:,:,1); 
            F_g=C(:,:,2); 
            F_b=C(:,:,3); 
            
            % to check known original colour
            colour_check=(F_r ~= F_g)|(F_g ~= F_b)|(F_b ~= F_r); %ignoring 256 possible 'perfect greyscale' colours that could be part of the original colour info
            
            known=find(colour_check); %locate and find the non zero elements (positions in a 1D matrix/image), 
            unknown= find(~colour_check);
            
            %Kernel construction
            sigma_1=app.sigma_1.Value; 
            sigma_2=app.sigma_2.Value;
            p=app.p.Value;
            delta=app.delta.Value;
            
            [X,Y]=meshgrid(1:l,1:k); %2D grid
            pixel_position=[X(:),Y(:)]; %row/column index
            position_known=pixel_position(known,:); %row/column of known colours
            
            grey_image=double(A); %convert to double for solving the linear system
            grey_val=grey_image(known); 
            
            numerator_spatial=pdist2(position_known,position_known);
            numerator_greyscale=abs(grey_val-grey_val').^p;
            
            %Gaussian RBF
            phi_spatial=exp(-(numerator_spatial/sigma_1).^2); %Gaussian
            phi_greyscale=exp(-(numerator_greyscale/sigma_2).^2); %Gaussian
            
            KD=phi_spatial.*phi_greyscale;
            
            %to satisfy matrix dimensions when solving the system
            C_reshaped=reshape(C,[],3); 
            f_s=C_reshaped(known,:); 
            f_s=double(f_s); %convert to double to solve linear system
            
            a_s=(KD+delta*eye(size(KD,1)))\f_s; 
            
            grey_values_all=grey_image(:); %covert to column vector to solve for F_s
            F_s=zeros(m,3); %initialise F_s
            
            %%%Solving for F_s
            interval=1000;
            for i=1:interval:m
               i_final=min(i+interval-1, m); %ensure it doesn't exceed m
            
               positions_interval=pixel_position(i:i_final, :);
               grey_interval=grey_values_all(i:i_final);
            
               dist_spatial_interval=pdist2(positions_interval, position_known);
               dist_gray_interval=abs(grey_interval-grey_val').^p;
            
               phi_spatial_interval=exp(-(dist_spatial_interval/sigma_1).^2); %Gaussian RBF
               phi_gray_interval=exp(-(dist_gray_interval/sigma_2).^2); %Gaussian RBF
            
               KD_full=phi_spatial_interval.*phi_gray_interval;
               F_s(i:i_final,:)=KD_full*a_s;
            end
            %%%Solving for F_s
            
            
            F_s=reshape(F_s,[k,l,3]); %convert back to original image dimensions
            F_s=uint8(F_s); %convert back to uint8, if F_s<0 -> F_s=0; if F_s>255 -> F_s=255 
            uialert(app.UIFigure, "Gaussian Recolourisation applied successfully!", "Success", "Icon", "success");
            app.Image_4.ImageSource=F_s;
        end

        % Button pushed function: CompactlySupportedButton
        function CompactlySupportedButtonPushed(app, event)
            [k,l,~]=size(app.OriginalImage);
            m=k*l;
            C=app.Image_3.ImageSource;
            A=app.Image_2.ImageSource;

            F_r=C(:,:,1); 
            F_g=C(:,:,2); 
            F_b=C(:,:,3); 
            
            % to check known original colour
            colour_check=(F_r ~= F_g)|(F_g ~= F_b)|(F_b ~= F_r); %ignoring 256 possible 'perfect greyscale' colours that could be part of the original colour info
            
            known=find(colour_check); %locate and find the non zero elements (positions in a 1D matrix/image), 
            unknown= find(~colour_check);
            
            %Kernel construction
            sigma_1=app.sigma_1.Value; 
            sigma_2=app.sigma_2.Value;
            p=app.p.Value;
            delta=app.delta.Value;
            
            [X,Y]=meshgrid(1:l,1:k); %2D grid
            pixel_position=[X(:),Y(:)]; %row/column index
            position_known=pixel_position(known,:); %row/column of known colours
            
            grey_image=double(A); %convert to double for solving the linear system
            grey_val=grey_image(known); 
            
            numerator_spatial=pdist2(position_known,position_known);
            numerator_greyscale=abs(grey_val-grey_val').^p;
            
            %Compactly Supported RBF
            phi_spatial=max((1-numerator_spatial/sigma_1),0).^4.*(4*(numerator_spatial/sigma_1)+1); %Compactly supported
            phi_greyscale=max((1-numerator_greyscale/sigma_2),0).^4.*(4*(numerator_greyscale/sigma_2)+1); %Compactly supported
            
            KD=phi_spatial.*phi_greyscale;
            
            %to satisfy matrix dimensions when solving the system
            C_reshaped=reshape(C,[],3); 
            f_s=C_reshaped(known,:); 
            f_s=double(f_s); %convert to double to solve linear system
            
            a_s=(KD+delta*eye(size(KD,1)))\f_s; 
            
            grey_values_all=grey_image(:); %covert to column vector to solve for F_s
            F_s=zeros(m,3); %initialise F_s
            
            %%%Solving for F_s
            interval=1000;
            for i=1:interval:m
               i_final=min(i+interval-1, m); %ensure it doesn't exceed m
            
               positions_interval=pixel_position(i:i_final, :);
               grey_interval=grey_values_all(i:i_final);
            
               dist_spatial_interval=pdist2(positions_interval, position_known);
               dist_gray_interval=abs(grey_interval-grey_val').^p;
            
               phi_spatial_interval=max((1-dist_spatial_interval/sigma_1),0).^4.*(4*(dist_spatial_interval/sigma_1)+1); %Compactly supported
               phi_gray_interval=max((1-dist_gray_interval/sigma_2),0).^4.*(4*(dist_gray_interval/sigma_2)+1); %Compactly supported
            
               KD_full=phi_spatial_interval.*phi_gray_interval;
               F_s(i:i_final,:)=KD_full*a_s;
            end
            %%%Solving for F_s
           
            F_s=reshape(F_s,[k,l,3]); %convert back to original image dimensions
            F_s=uint8(F_s); %convert back to uint8, if F_s<0 -> F_s=0; if F_s>255 -> F_s=255 
            uialert(app.UIFigure, "Compactly Supported Recolourisation applied successfully!", "Success", "Icon", "success");
            app.Image_4.ImageSource=F_s;
        end

        % Callback function
        function SaveImageButtonPushed(app, event)
            [file, path] = uiputfile({'*.jpg'; '*.png'; '*.bmp'}, 'Save Image As');
        
            if ischar(file)
                imwrite(app.Image_4.ImageSource, fullfile(path, file));
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed2(app, event)
            [file, path] = uiputfile({'*.jpg'; '*.png'}, 'Save Image As');
        
            if ischar(file)  
                imwrite(app.Image_4.ImageSource, fullfile(path, file));              
                uialert(app.UIFigure, "Image saved successfully!", "Success", "Icon", "success");
            end
        end

        % Button pushed function: CustomPointButton
        function CustomPointButtonPushed(app, event)
            grayscale=app.GreyscaleImage;
            original=app.OriginalImage;
        
            answer=inputdlg('Enter number of points:', 'Manual Selection', [1 35]);
            if isempty(answer)
                return; 
            end
            num_points = str2double(answer{1});
            if isnan(num_points) || num_points <= 0
                errordlg('Please enter a positive integer.');
                return;
            end
        
            fig=figure;
            imshow(grayscale);
            title(sprintf('Click %d points to recolour (Selected: 0)', num_points));
            
            x = zeros(num_points, 1);
            y = zeros(num_points, 1);
            
            for i = 1:num_points
                [x(i), y(i)] = ginput(1); 
                x(i) = round(x(i));
                y(i) = round(y(i));
        
                [img_h, img_w, ~] = size(original);
        
                title(sprintf('Click %d points to recolour (Selected: %d)', num_points, i));        
                hold on;
                plot(x(i), y(i), 'ro', 'MarkerSize', 3);
                hold off;
            end
            
            color_hints = zeros(num_points, 3);
            for i = 1:num_points
                color_hints(i, :) = original(y(i), x(i), :);
            end
            
            manual = grayscale;
            for i = 1:num_points
                manual(y(i), x(i), :) = color_hints(i, :);
            end
            app.Image_3.ImageSource = manual; 
            uialert(app.UIFigure, "Custom Points applied successfully!", "Success", "Icon", "success");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecolouristaionButtonGroup = uibuttongroup(app.UIFigure);
            app.RecolouristaionButtonGroup.Title = 'Recolouristaion';
            app.GaussianButton = uibutton(app.RecolouristaionButtonGroup, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.CompactlySupportedButton = uibutton(app.RecolouristaionButtonGroup, 'push');
            app.CompactlySupportedButton.ButtonPushedFcn = createCallbackFcn(app, @CompactlySupportedButtonPushed, true);
            app.CompactlySupportedButton.Text = 'Compactly Supported';
            app.SamplingOptionButtonGroup = uibuttongroup(app.UIFigure);
            app.SamplingOptionButtonGroup.Title = 'Sampling Option';
            app.CustomPointButton = uibutton(app.SamplingOptionButtonGroup, 'push');
            app.CustomPointButton.ButtonPushedFcn = createCallbackFcn(app, @CustomPointButtonPushed, true);
            app.CustomPointButton.Text = 'Custom Point';
            app.AreaSelectionButton = uibutton(app.SamplingOptionButtonGroup, 'push');
            app.AreaSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AreaSelectionButtonPushed, true);
            app.AreaSelectionButton.Text = 'Area Selection';
            app.UniformButton = uibutton(app.SamplingOptionButtonGroup, 'push');
            app.UniformButton.ButtonPushedFcn = createCallbackFcn(app, @UniformButtonPushed, true);
            app.UniformButton.Text = 'Uniform';
            app.RandomButton = uibutton(app.SamplingOptionButtonGroup, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.Image = uiimage(app.UIFigure);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.Image_2 = uiimage(app.UIFigure);
            app.GreyscaleLabel = uilabel(app.UIFigure);
            app.GreyscaleLabel.Text = 'Greyscale';
            app.Image_3 = uiimage(app.UIFigure);
            app.GreyscalewithOriginalColouringLabel = uilabel(app.UIFigure);
            app.GreyscalewithOriginalColouringLabel.Text = 'Greyscale with Original Colouring';
            app.Image_4 = uiimage(app.UIFigure);
            app.RecolourisedImageLabel = uilabel(app.UIFigure);
            app.RecolourisedImageLabel.Text = 'Recolourised Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed2, true);
            app.SaveImageButton.Text = 'Save Image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'σ_1';
            app.sigma_1 = uieditfield(app.UIFigure, 'numeric');
            app.sigma_1.Value = 100;
            app.EditField_2Label_2 = uilabel(app.UIFigure);
            app.EditField_2Label_2.Text = 'σ_2';
            app.sigma_2 = uieditfield(app.UIFigure, 'numeric');
            app.sigma_2.Value = 100;
            app.EditField_3Label_2 = uilabel(app.UIFigure);
            app.EditField_3Label_2.Text = 'p';
            app.p = uieditfield(app.UIFigure, 'numeric');
            app.p.Value = 0.5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'δ';
            app.delta = uieditfield(app.UIFigure, 'numeric');
            app.delta.Value = 0.0002;
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.percentage = uieditfield(app.UIFigure, 'numeric');
            app.percentage.Value = 0.1;
            app.KnownPixelsDesiredLabel = uilabel(app.UIFigure);
            app.KnownPixelsDesiredLabel.Text = 'Known Pixels Desired';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_colourisation_vJT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end